classdef PhasedArrayApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PhasedArrayAppUIFigure          matlab.ui.Figure
        ControlsLabel                   matlab.ui.control.Label
        FinishedLamp                    matlab.ui.control.Lamp
        FinishedLampLabel               matlab.ui.control.Label
        FrequencyGHzEditField           matlab.ui.control.NumericEditField
        FrequencyGHzEditFieldLabel      matlab.ui.control.Label
        DegEditField_2                  matlab.ui.control.NumericEditField
        DegLabel                        matlab.ui.control.Label
        DegEditField                    matlab.ui.control.NumericEditField
        DegLabel_2                      matlab.ui.control.Label
        BeamSteeringLabel               matlab.ui.control.Label
        PhaseBitsSpinner                matlab.ui.control.Spinner
        PhaseBitsSpinnerLabel           matlab.ui.control.Label
        NSpinner                        matlab.ui.control.Spinner
        NSpinnerLabel                   matlab.ui.control.Label
        MSpinner                        matlab.ui.control.Spinner
        MSpinnerLabel                   matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        TabSelectionListBox             matlab.ui.control.ListBox
        TabSelectionListBoxLabel        matlab.ui.control.Label
        FileLocationEditField           matlab.ui.control.EditField
        FileLocationEditFieldLabel      matlab.ui.control.Label
        ArrayInputsLabel                matlab.ui.control.Label
        Button                          matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        SaveButton                      matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        Tab_2                           matlab.ui.container.Tab
        SaveButton_5                    matlab.ui.control.Button
        GenerateButton_6                matlab.ui.control.Button
        Tab_3                           matlab.ui.container.Tab
        SaveButton_6                    matlab.ui.control.Button
        GenerateButton_7                matlab.ui.control.Button
        Tab_4                           matlab.ui.container.Tab
        SaveButton_7                    matlab.ui.control.Button
        GenerateButton_2                matlab.ui.control.Button
        DegLabel_4                      matlab.ui.control.Label
        DegLabel_3                      matlab.ui.control.Label
        CutAngleEditField               matlab.ui.control.NumericEditField
        CutAngleEditFieldLabel          matlab.ui.control.Label
        ViewRangeLabel                  matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        EndEditField                    matlab.ui.control.NumericEditField
        EndEditFieldLabel               matlab.ui.control.Label
        Tab_5                           matlab.ui.container.Tab
        SaveButton_8                    matlab.ui.control.Button
        CutAngleEditField_2             matlab.ui.control.NumericEditField
        CutAngleEditField_2Label        matlab.ui.control.Label
        StartEditField_2                matlab.ui.control.NumericEditField
        StartEditField_2Label           matlab.ui.control.Label
        EndEditField_2                  matlab.ui.control.NumericEditField
        EndEditField_2Label             matlab.ui.control.Label
        GenerateButton_3                matlab.ui.control.Button
        DegLabel_7                      matlab.ui.control.Label
        DegLabel_6                      matlab.ui.control.Label
        ViewRangeLabel_2                matlab.ui.control.Label
        DegLabel_5                      matlab.ui.control.Label
        Tab_6                           matlab.ui.container.Tab
        SaveButton_9                    matlab.ui.control.Button
        CutEditField                    matlab.ui.control.NumericEditField
        CutEditFieldLabel               matlab.ui.control.Label
        StartEditField_3                matlab.ui.control.NumericEditField
        StartEditField_3Label           matlab.ui.control.Label
        EndEditField_3                  matlab.ui.control.NumericEditField
        EndEditField_3Label             matlab.ui.control.Label
        GenerateButton_4                matlab.ui.control.Button
        UVViewRangeLabel                matlab.ui.control.Label
        VCheckBox                       matlab.ui.control.CheckBox
        UCheckBox                       matlab.ui.control.CheckBox
        Tab_7                           matlab.ui.container.Tab
        SaveButton_4                    matlab.ui.control.Button
        GenerateButton_5                matlab.ui.control.Button
        Tab_8                           matlab.ui.container.Tab
        PhaseBitsTestedLabel            matlab.ui.control.Label
        MostSpinner                     matlab.ui.control.Spinner
        MostSpinnerLabel                matlab.ui.control.Label
        LeastSpinner                    matlab.ui.control.Spinner
        LeastSpinner_2Label             matlab.ui.control.Label
        StandardCheckBox                matlab.ui.control.CheckBox
        DropDown_2                      matlab.ui.control.DropDown
        RRCheckBox                      matlab.ui.control.CheckBox
        MPEZCheckBox                    matlab.ui.control.CheckBox
        TDQCheckBox                     matlab.ui.control.CheckBox
        SaveButton_10                   matlab.ui.control.Button
        GenerateButton_8                matlab.ui.control.Button
        InteractButton_9                matlab.ui.control.Button
        AdvancedTab                     matlab.ui.container.Tab
        DropDown                        matlab.ui.control.DropDown
        SaveAsCheckBox                  matlab.ui.control.CheckBox
        dnEditField                     matlab.ui.control.NumericEditField
        dnEditFieldLabel                matlab.ui.control.Label
        PropagationSpeedEditField       matlab.ui.control.NumericEditField
        PropagationSpeedEditFieldLabel  matlab.ui.control.Label
        ElementSpacinginLambdaLabel     matlab.ui.control.Label
        dmEditField                     matlab.ui.control.NumericEditField
        dmEditFieldLabel                matlab.ui.control.Label
    end


    properties (Access = public)
        array = [];
        element = [];
        weights = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TabSelectionListBox
        function TabSelectionListBoxValueChanged(app, event)
            value = app.TabSelectionListBox.Value;
            switch value
                case '1. Array Geometry'
                    app.TabGroup.SelectedTab = app.Tab;
                case '2. Single Element Directivity Pattern'
                    app.TabGroup.SelectedTab = app.Tab_2;
                case '3. Array Directivity Pattern'
                    app.TabGroup.SelectedTab = app.Tab_3;
                case '4. Azmith Cut'
                    app.TabGroup.SelectedTab = app.Tab_4;
                case '5. Elevation Cut'
                    app.TabGroup.SelectedTab = app.Tab_5;
                case '6. UV Space'
                    app.TabGroup.SelectedTab = app.Tab_6;
                case '7. Grating Lobes'
                    app.TabGroup.SelectedTab = app.Tab_7;
                case '8. Quantization Lobes'
                    app.TabGroup.SelectedTab = app.Tab_8;
                case 'Advanced'
                    app.TabGroup.SelectedTab = app.AdvancedTab;
                otherwise
                    app.TabGroup.SelectedTab = app.Tab;

            end

        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            file= uipickfiles('FilterSpec','C:\Users\peter\Documents\UMass Masters\ECE 697L\',...
                'NumFiles',1,'Prompt','Pick ONE file related to your custom single element antenna (csv preferred)',...
                'Output','char','REFilter','.csv');
            if ~isa(file,'char')
                warndlg('No File Was Picked...Returning')
                return
            end
            app.FileLocationEditField.Value = file;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            Main(app);
%             app.array = a;
%             app.elem = e;
%             app.weights = w;

        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            value = app.TabGroup.SelectedTab.Title;
            switch value
                case '1'
                    app.TabSelectionListBox.Value = '1. Array Geometry';
                case '2'
                    app.TabSelectionListBox.Value ='2. Single Element Directivity Pattern';
                case '3'
                    app.TabSelectionListBox.Value = '3. Array Directivity Pattern';
                case '4'
                    app.TabSelectionListBox.Value = '4. Azmith Cut';
                case '5'
                    app.TabSelectionListBox.Value = '5. Elevation Cut';
                case '6'
                    app.TabSelectionListBox.Value = '6. UV Space';
                case '7'
                    app.TabSelectionListBox.Value = '7. Grating Lobes';
                case '8'
                    app.TabSelectionListBox.Value = '8. Quantization Lobes';
                otherwise
                    app.TabSelectionListBox.Value = 'Advanced';
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            arrayGeometry(app);
        end

        % Callback function: not associated with a component
        function DisplayButton_2Pushed(app, event)
            singElemPatt(app);
        end

        % Callback function: not associated with a component
        function DisplayButton_3Pushed(app, event)
            arrayPatt(app);
        end

        % Button pushed function: GenerateButton_2
        function GenerateButton_2Pushed(app, event)
            Az2D(app);
        end

        % Button pushed function: GenerateButton_3
        function GenerateButton_3Pushed(app, event)
            El2D(app);
        end

        % Button pushed function: GenerateButton_4
        function GenerateButton_4Pushed(app, event)
            UVpatt(app);
        end

        % Button pushed function: GenerateButton_5
        function GenerateButton_5Pushed(app, event)
            gratingLobes(app);
        end

        % Value changed function: UCheckBox
        function UCheckBoxValueChanged(app, event)
            value = app.UCheckBox.Value;
            if app.VCheckBox.Value == 1 && value == 1
                app.VCheckBox.Value = 0;
            end
        end

        % Value changed function: VCheckBox
        function VCheckBoxValueChanged(app, event)
            value = app.VCheckBox.Value;
            if app.UCheckBox.Value == 1 && value == 1
                app.UCheckBox.Value = 0;
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            saveButton(app);
        end

        % Button pushed function: GenerateButton_6
        function GenerateButton_6Pushed(app, event)
            singElemPatt(app);
        end

        % Button pushed function: GenerateButton_7
        function GenerateButton_7Pushed(app, event)
            arrayPatt(app);
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: SaveButton_6
        function SaveButton_6Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: SaveButton_7
        function SaveButton_7Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: SaveButton_8
        function SaveButton_8Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: SaveButton_9
        function SaveButton_9Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            saveButton(app);
        end

        % Button pushed function: GenerateButton_8
        function GenerateButton_8Pushed(app, event)
            Qlobes(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PhasedArrayAppUIFigure = uifigure('Visible', 'off');
            app.PhasedArrayAppUIFigure.Name = 'Phased Array App';
            app.TabGroup = uitabgroup(app.PhasedArrayAppUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1';
            app.GenerateButton = uibutton(app.Tab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.GenerateButton_6 = uibutton(app.Tab_2, 'push');
            app.GenerateButton_6.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_6Pushed, true);
            app.GenerateButton_6.Text = 'Generate';
            app.SaveButton_5 = uibutton(app.Tab_2, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Text = 'Save';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.GenerateButton_7 = uibutton(app.Tab_3, 'push');
            app.GenerateButton_7.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_7Pushed, true);
            app.GenerateButton_7.Text = 'Generate';
            app.SaveButton_6 = uibutton(app.Tab_3, 'push');
            app.SaveButton_6.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_6Pushed, true);
            app.SaveButton_6.Text = 'Save';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = '4';
            app.EndEditFieldLabel = uilabel(app.Tab_4);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.Tab_4, 'numeric');
            app.EndEditField.Limits = [-180 180];
            app.EndEditField.ValueDisplayFormat = '%.0f';
            app.EndEditField.Value = 180;
            app.StartEditFieldLabel = uilabel(app.Tab_4);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.Tab_4, 'numeric');
            app.StartEditField.Limits = [-180 180];
            app.StartEditField.ValueDisplayFormat = '%.0f';
            app.StartEditField.Value = -180;
            app.ViewRangeLabel = uilabel(app.Tab_4);
            app.ViewRangeLabel.Text = 'View Range';
            app.CutAngleEditFieldLabel = uilabel(app.Tab_4);
            app.CutAngleEditFieldLabel.Text = 'Cut Angle';
            app.CutAngleEditField = uieditfield(app.Tab_4, 'numeric');
            app.CutAngleEditField.Limits = [-90 90];
            app.CutAngleEditField.ValueDisplayFormat = '%.0f';
            app.DegLabel_3 = uilabel(app.Tab_4);
            app.DegLabel_3.Text = '[Deg]';
            app.DegLabel_4 = uilabel(app.Tab_4);
            app.DegLabel_4.Text = '[Deg]';
            app.GenerateButton_2 = uibutton(app.Tab_4, 'push');
            app.GenerateButton_2.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_2Pushed, true);
            app.GenerateButton_2.Text = 'Generate';
            app.SaveButton_7 = uibutton(app.Tab_4, 'push');
            app.SaveButton_7.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_7Pushed, true);
            app.SaveButton_7.Text = 'Save';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = '5';
            app.DegLabel_5 = uilabel(app.Tab_5);
            app.DegLabel_5.Text = '[Deg]';
            app.ViewRangeLabel_2 = uilabel(app.Tab_5);
            app.ViewRangeLabel_2.Text = 'View Range';
            app.DegLabel_6 = uilabel(app.Tab_5);
            app.DegLabel_6.Text = '[Deg]';
            app.DegLabel_7 = uilabel(app.Tab_5);
            app.DegLabel_7.Text = '[Deg]';
            app.GenerateButton_3 = uibutton(app.Tab_5, 'push');
            app.GenerateButton_3.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_3Pushed, true);
            app.GenerateButton_3.Text = 'Generate';
            app.EndEditField_2Label = uilabel(app.Tab_5);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.Tab_5, 'numeric');
            app.EndEditField_2.Limits = [-90 90];
            app.EndEditField_2.ValueDisplayFormat = '%.0f';
            app.EndEditField_2.Value = 90;
            app.StartEditField_2Label = uilabel(app.Tab_5);
            app.StartEditField_2Label.Text = 'Start';
            app.StartEditField_2 = uieditfield(app.Tab_5, 'numeric');
            app.StartEditField_2.Limits = [-90 90];
            app.StartEditField_2.ValueDisplayFormat = '%.0f';
            app.StartEditField_2.Value = -90;
            app.CutAngleEditField_2Label = uilabel(app.Tab_5);
            app.CutAngleEditField_2Label.Text = 'Cut Angle';
            app.CutAngleEditField_2 = uieditfield(app.Tab_5, 'numeric');
            app.CutAngleEditField_2.Limits = [-180 180];
            app.CutAngleEditField_2.ValueDisplayFormat = '%.0f';
            app.SaveButton_8 = uibutton(app.Tab_5, 'push');
            app.SaveButton_8.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_8Pushed, true);
            app.SaveButton_8.Text = 'Save';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = '6';
            app.UCheckBox = uicheckbox(app.Tab_6);
            app.UCheckBox.ValueChangedFcn = createCallbackFcn(app, @UCheckBoxValueChanged, true);
            app.UCheckBox.Text = 'U';
            app.VCheckBox = uicheckbox(app.Tab_6);
            app.VCheckBox.ValueChangedFcn = createCallbackFcn(app, @VCheckBoxValueChanged, true);
            app.VCheckBox.Text = 'V';
            app.UVViewRangeLabel = uilabel(app.Tab_6);
            app.UVViewRangeLabel.Text = 'UV View Range';
            app.GenerateButton_4 = uibutton(app.Tab_6, 'push');
            app.GenerateButton_4.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_4Pushed, true);
            app.GenerateButton_4.Text = 'Generate';
            app.EndEditField_3Label = uilabel(app.Tab_6);
            app.EndEditField_3Label.Text = 'End';
            app.EndEditField_3 = uieditfield(app.Tab_6, 'numeric');
            app.EndEditField_3.Limits = [-1 1];
            app.EndEditField_3.ValueDisplayFormat = '%.2f';
            app.EndEditField_3.Value = 1;
            app.StartEditField_3Label = uilabel(app.Tab_6);
            app.StartEditField_3Label.Text = 'Start';
            app.StartEditField_3 = uieditfield(app.Tab_6, 'numeric');
            app.StartEditField_3.Limits = [-1 1];
            app.StartEditField_3.ValueDisplayFormat = '%.2f';
            app.StartEditField_3.Value = -1;
            app.CutEditFieldLabel = uilabel(app.Tab_6);
            app.CutEditFieldLabel.Text = 'Cut';
            app.CutEditField = uieditfield(app.Tab_6, 'numeric');
            app.CutEditField.Limits = [-1 1];
            app.CutEditField.ValueDisplayFormat = '%.2f';
            app.SaveButton_9 = uibutton(app.Tab_6, 'push');
            app.SaveButton_9.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_9Pushed, true);
            app.SaveButton_9.Text = 'Save';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = '7';
            app.GenerateButton_5 = uibutton(app.Tab_7, 'push');
            app.GenerateButton_5.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_5Pushed, true);
            app.GenerateButton_5.Text = 'Generate';
            app.SaveButton_4 = uibutton(app.Tab_7, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = '8';
            app.InteractButton_9 = uibutton(app.Tab_8, 'push');
            app.InteractButton_9.Text = 'Interact';
            app.GenerateButton_8 = uibutton(app.Tab_8, 'push');
            app.GenerateButton_8.ButtonPushedFcn = createCallbackFcn(app, @GenerateButton_8Pushed, true);
            app.GenerateButton_8.Text = 'Generate';
            app.SaveButton_10 = uibutton(app.Tab_8, 'push');
            app.SaveButton_10.Text = 'Save';
            app.TDQCheckBox = uicheckbox(app.Tab_8);
            app.TDQCheckBox.Text = 'TDQ';
            app.MPEZCheckBox = uicheckbox(app.Tab_8);
            app.MPEZCheckBox.Text = 'MPEZ';
            app.RRCheckBox = uicheckbox(app.Tab_8);
            app.RRCheckBox.Text = 'RR';
            app.DropDown_2 = uidropdown(app.Tab_8);
            app.DropDown_2.Items = {'Up', 'Down'};
            app.DropDown_2.Value = 'Up';
            app.StandardCheckBox = uicheckbox(app.Tab_8);
            app.StandardCheckBox.Text = 'Standard';
            app.StandardCheckBox.Value = true;
            app.LeastSpinner_2Label = uilabel(app.Tab_8);
            app.LeastSpinner_2Label.Text = 'Least';
            app.LeastSpinner = uispinner(app.Tab_8);
            app.LeastSpinner.Limits = [0 Inf];
            app.LeastSpinner.Value = 2;
            app.MostSpinnerLabel = uilabel(app.Tab_8);
            app.MostSpinnerLabel.Text = 'Most';
            app.MostSpinner = uispinner(app.Tab_8);
            app.MostSpinner.Limits = [0 Inf];
            app.MostSpinner.Value = 8;
            app.PhaseBitsTestedLabel = uilabel(app.Tab_8);
            app.PhaseBitsTestedLabel.Text = 'Phase Bits Tested';
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.dmEditFieldLabel = uilabel(app.AdvancedTab);
            app.dmEditFieldLabel.Text = 'dm ';
            app.dmEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.dmEditField.Limits = [0 Inf];
            app.dmEditField.Value = 0.45;
            app.ElementSpacinginLambdaLabel = uilabel(app.AdvancedTab);
            app.ElementSpacinginLambdaLabel.Text = 'Element Spacing in Lambda';
            app.PropagationSpeedEditFieldLabel = uilabel(app.AdvancedTab);
            app.PropagationSpeedEditFieldLabel.Text = 'Propagation Speed';
            app.PropagationSpeedEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.PropagationSpeedEditField.Value = 300000000;
            app.dnEditFieldLabel = uilabel(app.AdvancedTab);
            app.dnEditFieldLabel.Text = 'dn';
            app.dnEditField = uieditfield(app.AdvancedTab, 'numeric');
            app.dnEditField.Limits = [0 Inf];
            app.dnEditField.Value = 0.45;
            app.SaveAsCheckBox = uicheckbox(app.AdvancedTab);
            app.SaveAsCheckBox.Text = 'Save As  .';
            app.SaveAsCheckBox.Value = true;
            app.DropDown = uidropdown(app.AdvancedTab);
            app.DropDown.Items = {'jpg', 'png', 'tiff', 'eps'};
            app.DropDown.Value = 'jpg';
            app.Button = uibutton(app.PhasedArrayAppUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.ArrayInputsLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.ArrayInputsLabel.Text = 'Array Inputs';
            app.FileLocationEditFieldLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.FileLocationEditFieldLabel.Text = 'File Location';
            app.FileLocationEditField = uieditfield(app.PhasedArrayAppUIFigure, 'text');
            app.TabSelectionListBoxLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.TabSelectionListBoxLabel.Text = 'Tab Selection';
            app.TabSelectionListBox = uilistbox(app.PhasedArrayAppUIFigure);
            app.TabSelectionListBox.Items = {'1. Array Geometry', '2. Single Element Directivity Pattern', '3. Array Directivity Pattern', '4. Azmith Cut', '5. Elevation Cut', '6. UV Space', '7. Grating Lobes', '8. Quantization Lobes', 'Advanced'};
            app.TabSelectionListBox.ValueChangedFcn = createCallbackFcn(app, @TabSelectionListBoxValueChanged, true);
            app.TabSelectionListBox.Value = '1. Array Geometry';
            app.ApplyButton = uibutton(app.PhasedArrayAppUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.MSpinnerLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.MSpinnerLabel.Text = 'M';
            app.MSpinner = uispinner(app.PhasedArrayAppUIFigure);
            app.MSpinner.Limits = [1 Inf];
            app.MSpinner.ValueDisplayFormat = '%.0f';
            app.MSpinner.Value = 32;
            app.NSpinnerLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.NSpinnerLabel.Text = 'N';
            app.NSpinner = uispinner(app.PhasedArrayAppUIFigure);
            app.NSpinner.Limits = [1 Inf];
            app.NSpinner.ValueDisplayFormat = '%.0f';
            app.NSpinner.Value = 32;
            app.PhaseBitsSpinnerLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.PhaseBitsSpinnerLabel.Text = 'Phase Bits';
            app.PhaseBitsSpinner = uispinner(app.PhasedArrayAppUIFigure);
            app.PhaseBitsSpinner.Limits = [1 Inf];
            app.PhaseBitsSpinner.ValueDisplayFormat = '%.0f';
            app.PhaseBitsSpinner.Value = 3;
            app.BeamSteeringLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.BeamSteeringLabel.Text = 'Beam Steering';
            app.DegLabel_2 = uilabel(app.PhasedArrayAppUIFigure);
            app.DegLabel_2.Text = 'Az [Deg]';
            app.DegEditField = uieditfield(app.PhasedArrayAppUIFigure, 'numeric');
            app.DegEditField.Limits = [0 90];
            app.DegEditField.RoundFractionalValues = 'on';
            app.DegLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.DegLabel.Text = 'El [Deg]';
            app.DegEditField_2 = uieditfield(app.PhasedArrayAppUIFigure, 'numeric');
            app.DegEditField_2.Limits = [0 90];
            app.DegEditField_2.RoundFractionalValues = 'on';
            app.DegEditField_2.Value = 90;
            app.FrequencyGHzEditFieldLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.FrequencyGHzEditFieldLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzEditField = uieditfield(app.PhasedArrayAppUIFigure, 'numeric');
            app.FinishedLampLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.FinishedLampLabel.Text = 'Finished';
            app.FinishedLamp = uilamp(app.PhasedArrayAppUIFigure);
            app.ControlsLabel = uilabel(app.PhasedArrayAppUIFigure);
            app.ControlsLabel.Text = 'Controls';
            % Show the figure after all components are created
            app.PhasedArrayAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhasedArrayApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PhasedArrayAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PhasedArrayAppUIFigure)
        end
    end
end